classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Button_12                matlab.ui.control.Button
        Button_11                matlab.ui.control.Button
        Button_10                matlab.ui.control.Button
        Button_9                 matlab.ui.control.Button
        Button_8                 matlab.ui.control.Button
        Button_7                 matlab.ui.control.Button
        Button_6                 matlab.ui.control.Button
        Button_5                 matlab.ui.control.Button
        Button_4                 matlab.ui.control.Button
        Button_3                 matlab.ui.control.Button
        Button_2                 matlab.ui.control.Button
        Button                   matlab.ui.control.Button
        R1ScrollsEditField       matlab.ui.control.EditField
        R1ScrollsEditFieldLabel  matlab.ui.control.Label
        FakeScrollEditField      matlab.ui.control.EditField
        FakeScrollLabel          matlab.ui.control.Label
        R2RealScrollsEditField   matlab.ui.control.EditField
        R2LabelEditFieldLabel    matlab.ui.control.Label
        StartButton              matlab.ui.control.Button
    end

    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            r2val = str2num(app.R2RealScrollsEditField.Value); %#ok<ST2NM>
            if isempty(r2val)
                uialert(app.UIFigure, 'Invalid R2 Real Scrolls. Enter numbers like: 1, 7, 5, 11', 'Input Error');
                return;
            end

    % Parse and validate Fake Scroll
            fakeval = str2num(app.FakeScrollEditField.Value); %#ok<ST2NM>
            if isempty(fakeval) || numel(fakeval) ~= 1
                uialert(app.UIFigure, 'Invalid Fake Scroll. Enter a single number like: 4', 'Input Error');
                return;
            end

    % Parse and validate R1 Scrolls
            r1val = str2num(app.R1ScrollsEditField.Value); %#ok<ST2NM>
            if isempty(r1val)
                uialert(app.UIFigure, 'Invalid R1 Scrolls. Enter numbers like: 12, 6, 10', 'Input Error');
                return;
            end

    % Push values to base workspace for the script to read
            assignin('base', 'gui_r2_real_scrolls', r2val);
            assignin('base', 'gui_fake_scroll',     fakeval);
            assignin('base', 'gui_r1_scrolls',      r1val);

    % Run the script
            run('robocon_r2final_5_1_1.m');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.R2LabelEditFieldLabel = uilabel(app.UIFigure);
            app.R2LabelEditFieldLabel.Text = 'R2 Real Scrolls :';
            app.R2RealScrollsEditField = uieditfield(app.UIFigure, 'text');
            app.FakeScrollLabel = uilabel(app.UIFigure);
            app.FakeScrollLabel.Text = 'Fake Scroll :';
            app.FakeScrollEditField = uieditfield(app.UIFigure, 'text');
            app.R1ScrollsEditFieldLabel = uilabel(app.UIFigure);
            app.R1ScrollsEditFieldLabel.Text = 'R1 Scrolls :';
            app.R1ScrollsEditField = uieditfield(app.UIFigure, 'text');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Text = '1';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.Text = '3';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.Text = '5';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.Text = '6';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.Text = '7';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.Text = '8';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.Text = '9';
            app.Button_10 = uibutton(app.UIFigure, 'push');
            app.Button_10.Text = '10';
            app.Button_11 = uibutton(app.UIFigure, 'push');
            app.Button_11.Text = '11';
            app.Button_12 = uibutton(app.UIFigure, 'push');
            app.Button_12.Text = '12';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end